classdef MFWF_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StopButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        FlightParametersTab             matlab.ui.container.Tab
        UseCycleAveragedForcesCheckBox  matlab.ui.control.CheckBox
        IdealSensorsCheckBox            matlab.ui.control.CheckBox
        CommandsTab                     matlab.ui.container.Tab
        EnablePhiCommandCheckBox        matlab.ui.control.CheckBox
        EnableThetaCommandCheckBox      matlab.ui.control.CheckBox
        EnablePsiCommandCheckBox        matlab.ui.control.CheckBox
        EnableZCommandCheckBox          matlab.ui.control.CheckBox
        EnableYCommandCheckBox          matlab.ui.control.CheckBox
        EnableXCommandCheckBox          matlab.ui.control.CheckBox
        CommandDropDown                 matlab.ui.control.DropDown
        CommandLabel                    matlab.ui.control.Label
        EditCommandButton               matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        OpenPresentationButton          matlab.ui.control.Button
        OpenPlotButton                  matlab.ui.control.Button
        PresentationAnimationCheckBox   matlab.ui.control.CheckBox
        ScopeControlsPanel              matlab.ui.container.Panel
        PositionButton                  matlab.ui.control.Button
        VelocityButton                  matlab.ui.control.Button
        AttitudeButton                  matlab.ui.control.Button
        ControllerButton                matlab.ui.control.Button
        PlottingAnimationCheckBox       matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        Button                          matlab.ui.control.Button
    end

    
    properties (Access = private)
        %These are the app wide variables used to work between different UI
        %objects
        
        mdl = 'MFWF_harness';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Opens the top level model.
            open('MFWF-FDC\harnesses\MFWF_harness.slx');
            
            %Set initial conditions for command controls
            value = evalin('base','inputs.Commands.xCommandEnable;');
            app.EnableXCommandCheckBox.Value = value;
            value = evalin('base','inputs.Commands.yCommandEnable;');
            app.EnableYCommandCheckBox.Value = value;
            value = evalin('base','inputs.Commands.zCommandEnable;');
            app.EnableZCommandCheckBox.Value = value;
            value = evalin('base','inputs.Commands.psiCommandEnable;');
            app.EnablePsiCommandCheckBox.Value = value;
            value = evalin('base','inputs.Commands.thetaCommandEnable;');
            app.EnableThetaCommandCheckBox.Value = value;
            value = evalin('base','inputs.Commands.phiCommandEnable;');
            app.EnablePhiCommandCheckBox.Value = value;
            
        end

        % Value changed function: IdealSensorsCheckBox
        function IdealSensorsCheckBoxValueChanged(app, event)
            %Enables or Disables the ideal sensor in top level model
            value = app.IdealSensorsCheckBox.Value;

            %Setting input structure value to match UI control
            if value == 1
                evalin('base', 'inputs.Sensor.Ideal = 1 ;');
            elseif value == 0
                evalin('base', 'inputs.Sensor.Ideal = 0 ;');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           %enable the stop button
           app.StopButton.Enable = true;
            
           %Run the simulation
           simout = sim(app.mdl);
           %simout unused for now


        end

        % Value changed function: PlottingAnimationCheckBox
        function PlottingAnimationCheckBoxValueChanged(app, event)
            %This button turns the animation on an off
            value = app.PlottingAnimationCheckBox.Value;
            
            %Setting input structure value to match UI control
            if value == 1
                evalin('base', 'inputs.Visualization.animation = 1 ;');
            elseif value == 0
                evalin('base', 'inputs.Visualization.animation = 0 ;');
            end


        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            %This button opens the velocity scope from the model.
            open_system('MFWF_harness/Visualization/Velocity')
        end

        % Button pushed function: AttitudeButton
        function AttitudeButtonPushed(app, event)
            %This button opens the attitude scope from the model.
            open_system('MFWF_harness/Visualization/Attitude')
        end

        % Button pushed function: PositionButton
        function PositionButtonPushed(app, event)
            %This button opens the position scope from the model.
            open_system('MFWF_harness/Visualization/Position')
        end

        % Button pushed function: ControllerButton
        function ControllerButtonPushed(app, event)
            %This button opens the controller scope from the model.
            open_system('MFWF_harness/Visualization/Controller Motor Output')
        end

        % Button pushed function: EditCommandButton
        function EditCommandButtonPushed(app, event)
            %Will open the variable editor for the selected variable. This
            %will allow a user to create a command using the UI.
            
            commandSelect = app.CommandDropDown.Value;
            
            if strcmp(commandSelect, 'X Command')
                openvar('inputs.Commands.xCommand');
            elseif strcmp(commandSelect, 'Y Command')
                openvar('inputs.Commands.yCommand');
            elseif strcmp(commandSelect, 'Z Command')
                openvar('inputs.Commands.zCommand');
            elseif strcmp(commandSelect, 'Psi Command')
                openvar('inputs.Commands.psiCommand');
            elseif strcmp(commandSelect, 'Theta Command')
                openvar('inputs.Commands.thetaCommand');
            elseif strcmp(commandSelect, 'Phi Command')
                openvar('inputs.Commands.phiCommand');
            end
        end

        % Value changed function: EnableXCommandCheckBox
        function EnableXCommandCheckBoxValueChanged(app, event)
            %This function will enable or disable the X Command
            value = app.EnableXCommandCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Commands.xCommandEnable = 1;');
            elseif value == 0
                evalin('base','inputs.Commands.xCommandEnable = 0;');
            end
        end

        % Value changed function: EnableYCommandCheckBox
        function EnableYCommandCheckBoxValueChanged(app, event)
            %This function will enable or disable the Y Command
            value = app.EnableYCommandCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Commands.yCommandEnable = 1;');
            elseif value == 0
                evalin('base','inputs.Commands.yCommandEnable = 0;');
            end
        end

        % Value changed function: EnableZCommandCheckBox
        function EnableZCommandCheckBoxValueChanged(app, event)
            %This function will enable or disable the Z Command
            value = app.EnableZCommandCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Commands.zCommandEnable = 1;');
            elseif value == 0
                evalin('base','inputs.Commands.zCommandEnable = 0;');
            end
        end

        % Value changed function: EnablePsiCommandCheckBox
        function EnablePsiCommandCheckBoxValueChanged(app, event)
            %This function will enable or disable the Psi Command
            value = app.EnablePsiCommandCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Commands.psiCommandEnable = 1;');
            elseif value == 0
                evalin('base','inputs.Commands.psiCommandEnable = 0;');
            end            
        end

        % Value changed function: EnableThetaCommandCheckBox
        function EnableThetaCommandCheckBoxValueChanged(app, event)
            %This function will enable or disable the Theta Command
            value = app.EnableThetaCommandCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Commands.thetaCommandEnable = 1;');
            elseif value == 0
                evalin('base','inputs.Commands.thetaCommandEnable = 0;');
            end 
        end

        % Value changed function: EnablePhiCommandCheckBox
        function EnablePhiCommandCheckBoxValueChanged(app, event)
            %This function will enable or disable the Phi Command
            value = app.EnablePhiCommandCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Commands.phiCommandEnable = 1;');
            elseif value == 0
                evalin('base','inputs.Commands.phiCommandEnable = 0;');
            end 
        end

        % Value changed function: UseCycleAveragedForcesCheckBox
        function UseCycleAveragedForcesCheckBoxValueChanged(app, event)
            %This button will turn the cycle averaged forces on and off.
            value = app.UseCycleAveragedForcesCheckBox.Value;
            
            if value == 1
                evalin('base','inputs.Plant.Aerodynamics.cycleAverage = 1;');
            elseif value == 0
                evalin('base','inputs.Plant.Aerodynamics.cycleAverage = 0;');
            end
        end

        % Value changed function: PresentationAnimationCheckBox
        function PresentationAnimationCheckBoxValueChanged(app, event)
            %This button turns the presentation animation on an off
            value = app.PresentationAnimationCheckBox.Value;
                        
            %Setting input structure value to match UI control
            if value == 1
                evalin('base', 'inputs.Visualization.presentation = 1 ;');
            elseif value == 0
                evalin('base', 'inputs.Visualization.presentation = 0 ;');
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %This button will abort the simulation
            
            set_param('MFWF_harness', 'SimulationCommand', 'stop');
            app.StopButton.Enable = false;
        end

        % Button pushed function: OpenPresentationButton
        function OpenPresentationButtonPushed(app, event)
            %This button with open the presentation mode animation
            
            open_system('MFWF_harness/Visualization/Presentation Animation/animation_Enabled/VR Sink2');
        end

        % Button pushed function: OpenPlotButton
        function OpenPlotButtonPushed(app, event)
            %This button will open the plotting 3d model
            
            open_system('MFWF_harness/Visualization/Plotting Animation/animation_Enabled/Flight Animation');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'button.svg';
            app.Button.Tooltip = {'This button will start the simulation with the chosen configuration.'};
            app.Button.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'MAAE4907N - ProjectLogo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FlightParametersTab = uitab(app.TabGroup);
            app.FlightParametersTab.Title = 'Flight Parameters';
            app.IdealSensorsCheckBox = uicheckbox(app.FlightParametersTab);
            app.IdealSensorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IdealSensorsCheckBoxValueChanged, true);
            app.IdealSensorsCheckBox.Tooltip = {'Turns the Ideal Sensors on and off. Unchecked box will turn Real Sensors on.'};
            app.IdealSensorsCheckBox.Text = {'Ideal Sensors'; ''};
            app.IdealSensorsCheckBox.Value = true;
            app.UseCycleAveragedForcesCheckBox = uicheckbox(app.FlightParametersTab);
            app.UseCycleAveragedForcesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCycleAveragedForcesCheckBoxValueChanged, true);
            app.UseCycleAveragedForcesCheckBox.Text = 'Use Cycle Averaged Forces';
            app.CommandsTab = uitab(app.TabGroup);
            app.CommandsTab.Title = 'Commands';
            app.EditCommandButton = uibutton(app.CommandsTab, 'push');
            app.EditCommandButton.ButtonPushedFcn = createCallbackFcn(app, @EditCommandButtonPushed, true);
            app.EditCommandButton.Tooltip = {'Edit desired command '};
            app.EditCommandButton.Text = 'Edit Command';
            app.CommandLabel = uilabel(app.CommandsTab);
            app.CommandLabel.Text = 'Command:';
            app.CommandDropDown = uidropdown(app.CommandsTab);
            app.CommandDropDown.Items = {'X Command', 'Y Command', 'Z Command', 'Psi Command', 'Theta Command', 'Phi Command'};
            app.CommandDropDown.Tooltip = {'Select desired command variable'};
            app.CommandDropDown.Value = 'X Command';
            app.EnableXCommandCheckBox = uicheckbox(app.CommandsTab);
            app.EnableXCommandCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableXCommandCheckBoxValueChanged, true);
            app.EnableXCommandCheckBox.Text = 'Enable X Command';
            app.EnableYCommandCheckBox = uicheckbox(app.CommandsTab);
            app.EnableYCommandCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableYCommandCheckBoxValueChanged, true);
            app.EnableYCommandCheckBox.Text = 'Enable Y Command';
            app.EnableZCommandCheckBox = uicheckbox(app.CommandsTab);
            app.EnableZCommandCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableZCommandCheckBoxValueChanged, true);
            app.EnableZCommandCheckBox.Text = 'Enable Z Command';
            app.EnablePsiCommandCheckBox = uicheckbox(app.CommandsTab);
            app.EnablePsiCommandCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablePsiCommandCheckBoxValueChanged, true);
            app.EnablePsiCommandCheckBox.Text = 'Enable Psi Command';
            app.EnableThetaCommandCheckBox = uicheckbox(app.CommandsTab);
            app.EnableThetaCommandCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableThetaCommandCheckBoxValueChanged, true);
            app.EnableThetaCommandCheckBox.Text = 'Enable Theta Command';
            app.EnablePhiCommandCheckBox = uicheckbox(app.CommandsTab);
            app.EnablePhiCommandCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablePhiCommandCheckBoxValueChanged, true);
            app.EnablePhiCommandCheckBox.Text = 'Enable Phi Command';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.PlottingAnimationCheckBox = uicheckbox(app.VisualizationTab);
            app.PlottingAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlottingAnimationCheckBoxValueChanged, true);
            app.PlottingAnimationCheckBox.Tooltip = {'Enable or Disable the 3D animation for the model'};
            app.PlottingAnimationCheckBox.Text = 'Plotting Animation';
            app.ScopeControlsPanel = uipanel(app.VisualizationTab);
            app.ScopeControlsPanel.Title = 'Scope Controls';
            app.ControllerButton = uibutton(app.ScopeControlsPanel, 'push');
            app.ControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ControllerButtonPushed, true);
            app.ControllerButton.Tooltip = {'Opens the Control Command and Motor scopes.'};
            app.ControllerButton.Text = 'Controller ';
            app.AttitudeButton = uibutton(app.ScopeControlsPanel, 'push');
            app.AttitudeButton.ButtonPushedFcn = createCallbackFcn(app, @AttitudeButtonPushed, true);
            app.AttitudeButton.Tooltip = {'Opens the attitude scopes.'};
            app.AttitudeButton.Text = 'Attitude';
            app.VelocityButton = uibutton(app.ScopeControlsPanel, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Tooltip = {'Opens the velocity scopes.'};
            app.VelocityButton.Text = 'Velocity';
            app.PositionButton = uibutton(app.ScopeControlsPanel, 'push');
            app.PositionButton.ButtonPushedFcn = createCallbackFcn(app, @PositionButtonPushed, true);
            app.PositionButton.Tooltip = {'Opens the position scopes.'};
            app.PositionButton.Text = 'Position';
            app.PresentationAnimationCheckBox = uicheckbox(app.VisualizationTab);
            app.PresentationAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PresentationAnimationCheckBoxValueChanged, true);
            app.PresentationAnimationCheckBox.Tooltip = {'Enable or Disable the 3D animation for the model'};
            app.PresentationAnimationCheckBox.Text = 'Presentation Animation';
            app.OpenPlotButton = uibutton(app.VisualizationTab, 'push');
            app.OpenPlotButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPlotButtonPushed, true);
            app.OpenPlotButton.Tooltip = {'Opens the Control Command and Motor scopes.'};
            app.OpenPlotButton.Text = 'Open Plot';
            app.OpenPresentationButton = uibutton(app.VisualizationTab, 'push');
            app.OpenPresentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPresentationButtonPushed, true);
            app.OpenPresentationButton.Tooltip = {'Opens the Control Command and Motor scopes.'};
            app.OpenPresentationButton.Text = 'Open Presentation';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFWF_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end